classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InputDataTab                    matlab.ui.container.Tab
        SelectFileTypeDropDownLabel     matlab.ui.control.Label
        SelectFileTypeDropDown          matlab.ui.control.DropDown
        Button                          matlab.ui.control.Button
        UploadLabel                     matlab.ui.control.Label
        NumberofInputVariablesEditFieldLabel  matlab.ui.control.Label
        NumberofInputVariablesEditField  matlab.ui.control.NumericEditField
        NumberofOutputVariablesEditFieldLabel  matlab.ui.control.Label
        NumberofOutputVariablesEditField  matlab.ui.control.NumericEditField
        NumberofRecordsEditFieldLabel   matlab.ui.control.Label
        NumberofRecordsEditField        matlab.ui.control.NumericEditField
        TrainingTestingSplitEditFieldLabel  matlab.ui.control.Label
        TrainingTestingSplitEditField   matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Image_7                         matlab.ui.control.Image
        UITable2                        matlab.ui.control.Table
        NextButton_5                    matlab.ui.control.Button
        INPUTDATALabel                  matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        SetHiddenUnitsTab               matlab.ui.container.Tab
        SelectNumberofHiddenUnitsEditFieldLabel  matlab.ui.control.Label
        SelectNumberofHiddenUnitsEditField  matlab.ui.control.NumericEditField
        SetHiddenUnitButton             matlab.ui.control.Button
        SelectMotherWaveletDropDownLabel  matlab.ui.control.Label
        SelectMotherWaveletDropDown     matlab.ui.control.DropDown
        SETHIDDENUNITSLabel             matlab.ui.control.Label
        Image_8                         matlab.ui.control.Image
        UIAxes5                         matlab.ui.control.UIAxes
        SelectMotherWaveletDropDownLabel_2  matlab.ui.control.Label
        SelectMotherWaveletDropDown_2   matlab.ui.control.DropDown
        ViewButton                      matlab.ui.control.Button
        UITable_11                      matlab.ui.control.Table
        SelectMotherWaveletDropDownLabel_3  matlab.ui.control.Label
        SelectMotherWaveletDropDown_3   matlab.ui.control.DropDown
        ViewButton_2                    matlab.ui.control.Button
        NextButton_6                    matlab.ui.control.Button
        RunModelTab_2                   matlab.ui.container.Tab
        NumberofEpochsIterationsEditField_2Label  matlab.ui.control.Label
        NumberofEpochsIterationsEditField_2  matlab.ui.control.NumericEditField
        MaximumErrorValueEditField_2Label  matlab.ui.control.Label
        MaximumErrorValueEditField_2    matlab.ui.control.NumericEditField
        LearningRateEditField_2Label    matlab.ui.control.Label
        LearningRateEditField_2         matlab.ui.control.NumericEditField
        RunModelButton_2                matlab.ui.control.Button
        MSEEditFieldLabel               matlab.ui.control.Label
        MSEEditField                    matlab.ui.control.NumericEditField
        TrainingIndicatorsLabel_2       matlab.ui.control.Label
        CorrelationCoefficientEditField_3Label  matlab.ui.control.Label
        CorrelationCoefficientEditField_3  matlab.ui.control.NumericEditField
        TestingIndicatorsLabel          matlab.ui.control.Label
        MSEEditField_2Label             matlab.ui.control.Label
        MSEEditField_2                  matlab.ui.control.NumericEditField
        CorrelationCoefficientEditField_4Label  matlab.ui.control.Label
        CorrelationCoefficientEditField_4  matlab.ui.control.NumericEditField
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        LearningRateEditField_2Label_2  matlab.ui.control.Label
        momentumrateEditField           matlab.ui.control.NumericEditField
        NextButton_3                    matlab.ui.control.Button
        PBiasEditFieldLabel             matlab.ui.control.Label
        PBiasEditField                  matlab.ui.control.NumericEditField
        PBiasEditField_2Label           matlab.ui.control.Label
        PBiasEditField_2                matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        RUNMODELLabel                   matlab.ui.control.Label
        Image_10                        matlab.ui.control.Image
        DownloadFinalWeightsButton      matlab.ui.control.Button
        FutureDataTab                   matlab.ui.container.Tab
        UploadFutureDataButton          matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        DownloadPredictedValuesButton   matlab.ui.control.Button
        NumberofRecordsEditField_2Label  matlab.ui.control.Label
        NumberofRecordsEditField_2      matlab.ui.control.NumericEditField
        SelectFileTypeDropDown_3Label   matlab.ui.control.Label
        SelectFileTypeDropDown_3        matlab.ui.control.DropDown
        UITable_9                       matlab.ui.control.Table
        FUTUREDATALabel                 matlab.ui.control.Label
        Image_12                        matlab.ui.control.Image
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        N % Number of Output Variables
        m % Number of Input Variables
        n % Number of Hidden Nodes
        P % Number of records used for training
        x % Input Data
        d % Output Data
        W % Hidden Unit Weights
        WW % Input to 20 Terms Weights
        a % Dilation Parameter
        b % Translation Parameter
        epo % number of iterations
        err % maximum error value
        lin %learning Rate
        trte %trainingtesting split
        totalrecords %total number of records
        xtest %testing input data
        dtest %testing output data
        xftr %future data without observed values
        yftr %simulated future values
        Property20 % Description
        Mtype %type of mother wavelet
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            type = string(app.SelectFileTypeDropDown.Value)
            P = app.NumberofRecordsEditField.Value
            m = app.NumberofInputVariablesEditField.Value
            N = app.NumberofOutputVariablesEditField.Value
            trte = app.TrainingTestingSplitEditField.Value
            char1 = 'A'
            char2 = ''
            char3 = ''
            char4 = ''
            char2int = m
            while (char2int > 0)
                modulo = mod((char2int - 1),26)
                char2 = append(string(char('A' + modulo)),char2);
                char2int = (char2int - modulo) / 26;
                char2int = floor(char2int)
            end
            char2int = m+1
            while (char2int > 0)
                modulo = mod((char2int - 1),26)
                char3 = append(string(char('A' + modulo)),char3);
                char2int = (char2int - modulo) / 26;
                char2int = floor(char2int)
            end
            char2int = m+N
            while (char2int > 0)
                modulo = mod((char2int - 1),26)
                char4 = append(string(char('A' + modulo)),char4);
                char2int = (char2int - modulo) / 26;
                char2int = floor(char2int)
            end
            totalrecords = P
            P = trte*P
            P = round(P)
            records = string(P)
            testingrecords1 = string(P+1)
            testingrecords2 = string(totalrecords)
            range1 = append(char1,'1:',char2,records)
            range2 = append(char3,'1:',char4,records)
            range3 = append(char1,testingrecords1,':',char2,testingrecords2)
            range4 = append(char3,testingrecords1,':',char4,testingrecords2)
              if type == 'Excel'
                [file,path,indx] = uigetfile('*.xlsx')
                x = xlsread(file,range1)
                d = xlsread(file,range2)
                xtest = xlsread(file,range3)
                dtest = xlsread(file,range4)
              end
              figure(app.UIFigure)
               if type == 'CSV'
                 [file,path,indx] = uigetfile('*.csv')
                x = csvread(file,0,0,range1)
                d = csvread(file,0,m,range2)
                xtest = csvread(file,P,0,range3)
                dtest = csvread(file,P,m,range4)
               end
               figure(app.UIFigure)
            app.P = P
            app.m = m
            app.x = x
            app.d = d
            app.N = N
            app.trte = trte
            app.totalrecords = totalrecords
            app.xtest = xtest
            app.dtest = dtest
            data1 = horzcat(x,d)
            data2 = horzcat(xtest,dtest)
            data = vertcat(data1,data2)
            app.UITable2.Data = data
        end

        % Button pushed function: SetHiddenUnitButton
        function SetHiddenUnitButtonPushed(app, event)
            n = app.SelectNumberofHiddenUnitsEditField.Value
            app.n = n
            mtype = string(app.SelectMotherWaveletDropDown.Value)
            app.Mtype = mtype
            n = app.n
            P = app.P
            m = app.m
            N = app.N
            x = app.x
            d = app.d
            W=rand(N,n)
            WW=rand(n,m)
            a=ones(1,n)
            b = ones(1,n)
            for j=1:n
                b(j)=j*P/n
            end
            app.a = a
            app.b = b
            app.W = W
            app.WW = WW
            mtype = string(mtype)
            if mtype == 'Mexican Hat Wavelet'
                      u = -2*pi:pi/100:2*pi
                      phi=(1-u.^2)
                      phi2=exp(-u.*u/2)
                      phi = phi.*phi2
                      plot(app.UIAxes5,u,phi,"Green")
          end
          if mtype == 'First Gaussian Wavelet'
              u = -2*pi:pi/100:2*pi
              phi=exp(-u.*u/2)/sqrt(2*pi)
              plot(app.UIAxes5,u,phi,"Green")
          end
          if mtype == 'Shannon Wavelet'
              u = -2*pi:pi/100:2*pi
              phi=sin(pi*(u-0.5))-sin(2*pi*(u-0.5))
              phi = phi./(pi*(u-0.5))
              plot(app.UIAxes5,u,phi,"Green")
          end
          if mtype == 'Meyer Wavelet'
              u = -2*pi:pi/100:2*pi
              phi= 35*u.^4-84*u.^5+70*u.^6-20*u.^7
              plot(app.UIAxes5,u,phi,"Green")
          end
          if mtype == 'Morlet Wavelet'
             u = -2*pi:pi/100:2*pi 
             phi=cos(1.75*u).*exp(-u.*u/2)
             plot(app.UIAxes5,u,phi,"Green")
          end
        end

        % Callback function
        function SelectRandomWeightsButtonPushed(app, event)
            n = app.n
            P = app.P
            m = app.m
            N = app.N
            x = app.x
            d = app.d
            W=rand(N,n)
            WW=rand(n,m)
            a=ones(1,n)
            for j=1:n
            b(j)=j*P/n;
            end
            app.a = a
            app.b = b
            app.W = W
            app.WW = WW
            app.UITable.Data = WW
            app.UITable_2.Data = a
            app.UITable_3.Data = b
            app.UITable_4.Data = W
        end

        % Callback function
        function SetCustomWWValuesButtonPushed(app, event)
            WW = app.WW
            n = app.n
            m = app.m
            type = app.SelectFileTypeDropDown_2.Value
            char1 = 'A'
            char2 = string(char('A'+(m-1)))
            num = string(n)
            range = append(char1,'1:',char2,num)
            if type == 'Excel'
                [file,path,indx] = uigetfile('*.xlsx')
                WW = xlsread(file,range)
            end
            figure(app.UIFigure)
            app.WW = WW
            app.UITable.Data = WW
        end

        % Callback function
        function SetCustomaValuesButtonPushed(app, event)
            a = app.a
            n = app.n
            N = 1
            type = app.SelectFileTypeDropDown_2.Value
            char1 = 'A'
            char2 = string(char('A'+(n-1)))
            num = string(N)
            range = append(char1,'1:',char2,num)
            if type == 'Excel'
                [file,path,indx] = uigetfile('*.xlsx')
                a = xlsread(file,range)
            end
            figure(app.UIFigure)
            app.a = a
            app.UITable_2.Data = a
        end

        % Callback function
        function SetCustombValuesButtonPushed(app, event)
            b = app.b
            n = app.n
            N = 1
            type = app.SelectFileTypeDropDown_2.Value
            char1 = 'A'
            char2 = string(char('A'+(n-1)))
            num = string(N)
            range = append(char1,'1:',char2,num)
            if type == 'Excel'
                [file,path,indx] = uigetfile('*.xlsx')
                b = xlsread(file,range)
            end
            figure(app.UIFigure)
            app.b = b 
            app.UITable_3.Data = b
        end

        % Callback function
        function SetCustomWValuesButtonPushed(app, event)
            W = app.W
            n = app.n
            N = app.N
            type = app.SelectFileTypeDropDown_2.Value
            char1 = 'A'
            char2 = string(char('A'+(n-1)))
            num = string(N)
            range = append(char1,'1:',char2,num)
            if type == 'Excel'
                [file,path,indx] = uigetfile('*.xlsx')
                W = xlsread(file,range)
            end
            figure(app.UIFigure)
            app.W = W
            app.UITable_4.Data = W
        end

        % Button pushed function: RunModelButton_2
        function RunModelButton_2Pushed(app, event)
            n = app.n
            P = app.P
            m = app.m
            N = app.N
            x = app.x
            d = app.d
            a = app.a
            b = app.b
            W = app.W
            WW = app.WW
            mtype = string(app.Mtype)
            epo = app.NumberofEpochsIterationsEditField_2.Value
            err = app.MaximumErrorValueEditField_2.Value
            lin = app.LearningRateEditField_2.Value
            epoch=1; % First iteration (variable to loop through number of iterations)
            error=err+1; % Initial Error Value (Anything greater than err value) 
            delta =1;
            while (error>=err & epoch<=epo)
                 
                 u=0;% MIDDLE VARIABLE 
                 %%%% Calculating the NET INPUT
                 for p=1:P
                     for j=1:n
                         u=0;
                          for k=1:m
                              u=u+WW(j,k)*x(p,k);
                          end
                          net(p,j)=u;
                          
                     end
                 end
                 
          for p=1:P
              for j=1:n
                  u=net(p,j);
                  u=(u-b(j))/a(j);
                  if mtype == 'Mexican Hat Wavelet'
                      phi(p,j)=(1-u^2)*exp(-u*u/2);
                  end
                  if mtype == 'First Gaussian Wavelet'
                      phi(p,j)=exp(-u*u/2)/sqrt(2*pi)
                  end
                  if mtype == 'Shannon Wavelet'
                      phi(p,j)=(sin(pi*(u-0.5))-sin(2*pi*(u-0.5)))/(pi*(u-0.5))
                  end
                  if mtype == 'Meyer Wavelet'
                      phi(p,j)= 35*u^4-84*u^5+70*u^6-20*u^7
                  end
                  if mtype == 'Morlet Wavelet'
                      phi(p,j)=cos(1.75*u)*exp(-u*u/2);
                  end
              end
          end
                      
                  %calculation of NETWORK OUTPUT
                 for p=1:P
                     for i=1:N
                         u=0;
                         for j=1:n
                             u=u+W(i,j)*phi(p,j);
                         end
                         y(p,i)=delta*abs(u);
                     end
                 end
                   %calculation of OUTPUT ERROR
                   u=0;
                 for p=1:P
                     for i=1:N
                        %u=u+abs(d(p,i)*log(y(p,i))+(1-d(p,i)*log(1-y(p,i)))); %logarithmic error
                        u=u+(d(p,i)-y(p,i))^2;
                     end
                 end
                 
                 %u=u/2 %FOR MORLET WAVELET
                 
                 error=u;
                 
                 %calculate of NETWORK GRADIENTS
                 for i=1:N
                     for j=1:n
                         u=0;
                         for p=1:P
                             u=u+(d(p,i)-y(p,i))*phi(p,j);
                         end
                         EW(i,j)=u;
                     end
                 end
                 
                 for j=1:n
                     for k=1:m
                         u=0;
                         for p=1:P
                             for i=1:N
                                u=u+(d(p,i)-y(p,i))*W(i,j)*phi(p,j)*x(p,k)/a(j) ;
                             end
                         end
                         EWW(j,k)=u;
                     end 
                 end 
                 
                 for j=1:n
                         u=0;
                         for p=1:P
                             for i=1:N
                                u=u+(d(p,i)-y(p,i))*W(i,j)*phi(p,j)/a(j) ;
                             end
                         end
                         Eb(j)=u;
                 end 
                 
                 for j=1:n
                         u=0;
                         for p=1:P
                             for i=1:N
                                u=u+(d(p,i)-y(p,i))*W(i,j)*phi(p,j)*((net(p,j)-b(j))/b(j))/a(j) ;
                             end
                         end
                         Ea(j)=u;
                 end 
                 
                %WEIGHT ADJUSTMENT
                WW=WW-lin*EWW;
                W=W-lin*EW;
                a=a-lin*Ea;
                b=b-lin*Eb;
                
                 %ITERATION INCREASED BY 1
                 epoch=epoch+1;
            
            end
            anser = immse(d,y)
            anser2 = corr2(d,y)
            pbias = ((y-d)/d)*100
            app.MSEEditField.Value = anser
            app.CorrelationCoefficientEditField_3.Value = anser2
            app.PBiasEditField.Value = mean(pbias(:,1))
            app.W = W
            app.WW = WW
            app.a = a
            app.b = b
            
            %TESTING DATA 
            totalrecords = app.totalrecords
            Ptest = totalrecords - P
            xtest = app.xtest
            dtest = app.dtest
            ytest = dtest
            n = app.n
            m = app.m
            for p=1:Ptest
             for j=1:n
                 u=0;
                  for k=1:m
                      u=u+WW(j,k)*xtest(p,k);
                  end
                  net(p,j)=u;       
             end
            end
            for p=1:Ptest
              for j=1:n
                  u=net(p,j);
                  u=(u-b(j))/a(j);
                  if mtype == 'Mexican Hat Wavelet'
                      phi(p,j)=(1-u^2)*exp(-u*u/2);
                  end
                  if mtype == 'First Gaussian Wavelet'
                      phi(p,j)=exp(-u*u/2)/sqrt(2*pi)
                  end
                  if mtype == 'Shannon Wavelet'
                      phi(p,j)=(sin(pi*(u-0.5))-sin(2*pi*(u-0.5)))/(pi*(u-0.5))
                  end
                  if mtype == 'Meyer Wavelet'
                      phi(p,j)= 35*u^4-84*u^5+70*u^6-20*u^7
                  end
                  if mtype == 'Morlet Wavelet'
                     phi(p,j)=cos(1.75*u)*exp(-u*u/2);
                  end
              end
            end
            for p=1:Ptest
             for i=1:N
                 u=0;
                 for j=1:n
                     u=u+W(i,j)*phi(p,j);
                 end
                 ytest(p,i)=delta*abs(u);
             end
            end
            anser3 = immse(dtest,ytest)
            app.MSEEditField_2.Value = anser3
            anser4 = corr2(dtest,ytest)
            pbias2 = ((ytest-dtest)/dtest)*100
            app.CorrelationCoefficientEditField_4.Value = anser4
            app.PBiasEditField_2.Value = mean(pbias2(:,1))
            A = x(:,1)
            Atest = xtest(:,1)
            scatter(app.UIAxes_2,y,d,"Red","filled")
            hold(app.UIAxes_2,'on')
            %diagonal line
            maxy = max(y)
            maxd = max(d)
            miny = min(y)
            mind = min(d)
            minn = min(miny,mind)
            maxn = max(maxy,maxd)
            temparr = [minn;maxn]
            plot(app.UIAxes_2,temparr,temparr,"Green")
            hold(app.UIAxes_2,'off')
            scatter(app.UIAxes_3,ytest,dtest,"Red","filled")
            hold(app.UIAxes_3,'on')
            maxy = max(ytest)
            maxd = max(dtest)
            miny = min(ytest)
            mind = min(dtest)
            minn = min(miny,mind)
            maxn = max(maxy,maxd)
            temparr = [minn;maxn]
            plot(app.UIAxes_3,temparr,temparr,"Green")
            hold(app.UIAxes_3,'off')
        end

        % Callback function
        function NextButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.RunModelTab_2
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.FutureDataTab
        end

        % Button pushed function: UploadFutureDataButton
        function UploadFutureDataButtonPushed(app, event)
            mtype = string(app.Mtype)
            NR = app.NumberofRecordsEditField_2.Value
            type = string(app.SelectFileTypeDropDown_3.Value)
            m = app.m
            char1 = 'A'
            char2 = string(char('A'+(m-1)))
            records = string(NR)
            range = append(char1,'1:',char2,records)
            if type == 'Excel'
                [file,path,indx] = uigetfile('*.xlsx')
                xftr = xlsread(file,range)
            end
            if type == 'CSV'
                [file,path,indx] = uigetfile('*.csv')
                xftr = readtable(file,'Range',range,"ReadVariableNames",false)
                xftr = table2array(xftr)
            end
            figure(app.UIFigure)
            n = app.n
            N = app.N
            WW = app.WW
            W = app.W
            a = app.a
            b = app.b
            P = NR
            for p=1:P
             for j=1:n
                 u=0;
                  for k=1:m
                      u=u+WW(j,k)*xftr(p,k);
                  end
                  net(p,j)=u;       
             end
            end
            for p=1:P
              for j=1:n
                  u=net(p,j);
                  u=(u-b(j))/a(j); %Z Term
                  if mtype == 'Mexican Hat Wavelet'
                      phi(p,j)=(1-u^2)*exp(-u*u/2);
                  end
                  if mtype == 'First Gaussian Wavelet'
                      phi(p,j)=exp(-u*u/2)/sqrt(2*pi)
                  end
                  if mtype == 'Shannon Wavelet'
                      phi(p,j)=(sin(pi*(u-0.5))-sin(2*pi*(u-0.5)))/(pi*(u-0.5))
                  end
                  if mtype == 'Meyer Wavelet'
                      phi(p,j)= 35*u^4-84*u^5+70*u^6-20*u^7
                  end
                  if mtype == 'Morlet Wavelet'
                     phi(p,j)=cos(1.75*u)*exp(-u*u/2);
                  end
              end
            end
            for p=1:P
             for i=1:N
                 u=0;
                 for j=1:n
                     u=u+W(i,j)*phi(p,j);
                 end
                 yftr(p,i)=abs(u);
             end
            end
            x1 = xftr(:,1)
            y1 = yftr(:,1)
            plot(app.UIAxes4,x1,y1)
            app.UITable_9.Data = yftr
            app.xftr = xftr
            app.yftr = yftr
        end

        % Callback function
        function DownloadallValuesButtonPushed(app, event)
            WW = app.WW
            WW = array2table(WW)
            W = app.W
            W = array2table(W)
            a = app.a
            a = array2table(a)
            b = app.b
            b = array2table(b)
            writetable(WW,'WWOutput.xlsx')
            writetable(W,'WOutput.xlsx')
            writetable(a,'aOutput.xlsx')
            writetable(b,'bOutput.xlsx')
        end

        % Callback function
        function NextButton_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.FutureDataTab
        end

        % Callback function
        function DownloadWWValuesButtonPushed(app, event)
            WW = app.WW
            WW = array2table(WW)
            writetable(WW,'WWOutput.xlsx')
            a = app.a
            a = array2table(a)
            writetable(a,'aOutput.xlsx')
            b = app.b
            b = array2table(b)
            writetable(b,'bOutput.xlsx')
        end

        % Callback function
        function DownloadWValuesButtonPushed(app, event)
            W = app.W
            W = array2table(W)
            writetable(W,'WOutput.xlsx')
        end

        % Callback function
        function DownloadaValuesButtonPushed(app, event)
            a = app.a
            a = array2table(a)
            writetable(a,'aOutput.xlsx')
        end

        % Callback function
        function DownloadbValuesButtonPushed(app, event)
            b = app.b
            b = array2table(b)
            writetable(b,'bOutput.xlsx')
        end

        % Button pushed function: DownloadPredictedValuesButton
        function DownloadPredictedValuesButtonPushed(app, event)
            xftr = app.xftr
            yftr = app.yftr
            futuredata = horzcat(xftr,yftr)
            futuredata = array2table(futuredata)
            writetable(futuredata,'futuredataOutput.xlsx')
        end

        % Button pushed function: NextButton_5
        function NextButton_5Pushed(app, event)
            app.TabGroup.SelectedTab = app.SetHiddenUnitsTab
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            mtype = app.SelectMotherWaveletDropDown_2.Value
            mtype = string(mtype)
            if mtype == 'Mexican Hat Wavelet'
                      u = -2*pi:pi/100:2*pi
                      phi=(1-u.^2)
                      phi2=exp(-u.*u/2)
                      phi = phi.*phi2
                      plot(app.UIAxes5,u,phi,"Green")
          end
          if mtype == 'First Gaussian Wavelet'
              u = -2*pi:pi/100:2*pi
              phi=exp(-u.*u/2)/sqrt(2*pi)
              plot(app.UIAxes5,u,phi,"Green")
          end
          if mtype == 'Shannon Wavelet'
              u = -2*pi:pi/100:2*pi
              phi=sin(pi*(u-0.5))-sin(2*pi*(u-0.5))
              phi = phi./(pi*(u-0.5))
              plot(app.UIAxes5,u,phi,"Green")
          end
          if mtype == 'Meyer Wavelet'
              u = -2*pi:pi/100:2*pi
              phi= 35*u.^4-84*u.^5+70*u.^6-20*u.^7
              plot(app.UIAxes5,u,phi,"Green")
          end
          if mtype == 'Morlet Wavelet'
             u = -2*pi:pi/100:2*pi 
             phi=cos(1.75*u).*exp(-u.*u/2)
             plot(app.UIAxes5,u,phi,"Green")
          end
          
        end

        % Button pushed function: ViewButton_2
        function ViewButton_2Pushed(app, event)
            varname = string(app.SelectMotherWaveletDropDown_3.Value)
            if varname == 'W'
                W = app.W
                app.UITable_11.Data = W
            end
            if varname == 'WW'
                WW = app.WW
                app.UITable_11.Data = WW
            end
            if varname == 'a'
                a = app.a
                app.UITable_11.Data = a
            end
            if varname == 'b'
                b = app.b
                app.UITable_11.Data = b
            end
        end

        % Callback function
        function GenerateRandomWeightsButtonPushed(app, event)
            n = app.n
            P = app.P
            m = app.m
            N = app.N
            x = app.x
            d = app.d
            W=rand(N,n)
            WW=rand(n,m)
            a=ones(1,n)
            b = ones(1,n)
            for j=1:n
                b(j)=j*P/n
            end
            app.a = a
            app.b = b
            app.W = W
            app.WW = WW
        end

        % Button pushed function: NextButton_6
        function NextButton_6Pushed(app, event)
            app.TabGroup.SelectedTab = app.RunModelTab_2
        end

        % Button pushed function: DownloadFinalWeightsButton
        function DownloadFinalWeightsButtonPushed(app, event)
            W = app.W
            W = array2table(W)
            writetable(W,'Output.xlsx','Sheet',2)
            WW = app.WW
            WW = array2table(WW)
            writetable(WW,'Output.xlsx','Sheet',1)
            a = app.a
            a = array2table(a)
            writetable(a,'Output.xlsx','Sheet',3)
            b = app.b
            b = array2table(b)
            writetable(b,'Output.xlsx','Sheet',4)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputDataTab = uitab(app.TabGroup);
            app.InputDataTab.Title = 'Input Data';
            app.SelectFileTypeDropDownLabel = uilabel(app.InputDataTab);
            app.SelectFileTypeDropDownLabel.Text = 'Select File Type';
            app.SelectFileTypeDropDown = uidropdown(app.InputDataTab);
            app.SelectFileTypeDropDown.Items = {'Excel', 'CSV'};
            app.SelectFileTypeDropDown.Value = 'Excel';
            app.Button = uibutton(app.InputDataTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = '418eAHsdh0L.png';
            app.Button.Text = '';
            app.UploadLabel = uilabel(app.InputDataTab);
            app.UploadLabel.Text = 'Upload';
            app.NumberofInputVariablesEditFieldLabel = uilabel(app.InputDataTab);
            app.NumberofInputVariablesEditFieldLabel.Text = 'Number of Input Variables';
            app.NumberofInputVariablesEditField = uieditfield(app.InputDataTab, 'numeric');
            app.NumberofOutputVariablesEditFieldLabel = uilabel(app.InputDataTab);
            app.NumberofOutputVariablesEditFieldLabel.Text = 'Number of Output Variables';
            app.NumberofOutputVariablesEditField = uieditfield(app.InputDataTab, 'numeric');
            app.NumberofRecordsEditFieldLabel = uilabel(app.InputDataTab);
            app.NumberofRecordsEditFieldLabel.Text = 'Number of Records';
            app.NumberofRecordsEditField = uieditfield(app.InputDataTab, 'numeric');
            app.TrainingTestingSplitEditFieldLabel = uilabel(app.InputDataTab);
            app.TrainingTestingSplitEditFieldLabel.Text = 'Training-Testing Split';
            app.TrainingTestingSplitEditField = uieditfield(app.InputDataTab, 'numeric');
            app.TrainingTestingSplitEditField.Value = 0.6;
            app.Label = uilabel(app.InputDataTab);
            app.Label.Text = '[0-1]';
            app.Image_7 = uiimage(app.InputDataTab);
            app.Image_7.ImageSource = 'bits logo.png';
            app.UITable2 = uitable(app.InputDataTab);
            app.UITable2.ColumnName = {''};
            app.UITable2.RowName = {};
            app.NextButton_5 = uibutton(app.InputDataTab, 'push');
            app.NextButton_5.ButtonPushedFcn = createCallbackFcn(app, @NextButton_5Pushed, true);
            app.NextButton_5.Text = 'Next>>';
            app.INPUTDATALabel = uilabel(app.InputDataTab);
            app.INPUTDATALabel.Text = 'INPUT DATA';
            app.Image5 = uiimage(app.InputDataTab);
            app.Image5.ImageSource = 'vjh.png';
            app.SetHiddenUnitsTab = uitab(app.TabGroup);
            app.SetHiddenUnitsTab.Title = 'Set Hidden Units';
            app.SelectNumberofHiddenUnitsEditFieldLabel = uilabel(app.SetHiddenUnitsTab);
            app.SelectNumberofHiddenUnitsEditFieldLabel.Text = 'Select Number of Hidden Units';
            app.SelectNumberofHiddenUnitsEditField = uieditfield(app.SetHiddenUnitsTab, 'numeric');
            app.SelectNumberofHiddenUnitsEditField.Value = 20;
            app.SetHiddenUnitButton = uibutton(app.SetHiddenUnitsTab, 'push');
            app.SetHiddenUnitButton.ButtonPushedFcn = createCallbackFcn(app, @SetHiddenUnitButtonPushed, true);
            app.SetHiddenUnitButton.Text = 'Set Hidden Unit';
            app.SelectMotherWaveletDropDownLabel = uilabel(app.SetHiddenUnitsTab);
            app.SelectMotherWaveletDropDownLabel.Text = 'Select Mother Wavelet';
            app.SelectMotherWaveletDropDown = uidropdown(app.SetHiddenUnitsTab);
            app.SelectMotherWaveletDropDown.Items = {'Mexican Hat Wavelet', 'Shannon Wavelet', 'Meyer Wavelet', 'Morlet Wavelet', 'First Gaussian Wavelet'};
            app.SelectMotherWaveletDropDown.Value = 'Mexican Hat Wavelet';
            app.SETHIDDENUNITSLabel = uilabel(app.SetHiddenUnitsTab);
            app.SETHIDDENUNITSLabel.Text = 'SET HIDDEN UNITS';
            app.Image_8 = uiimage(app.SetHiddenUnitsTab);
            app.Image_8.ImageSource = 'bits logo.png';
            app.UIAxes5 = uiaxes(app.SetHiddenUnitsTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.SelectMotherWaveletDropDownLabel_2 = uilabel(app.SetHiddenUnitsTab);
            app.SelectMotherWaveletDropDownLabel_2.Text = 'Select Mother Wavelet';
            app.SelectMotherWaveletDropDown_2 = uidropdown(app.SetHiddenUnitsTab);
            app.SelectMotherWaveletDropDown_2.Items = {'Mexican Hat Wavelet', 'Shannon Wavelet', 'Meyer Wavelet', 'Morlet Wavelet', 'First Gaussian Wavelet'};
            app.SelectMotherWaveletDropDown_2.Value = 'Mexican Hat Wavelet';
            app.ViewButton = uibutton(app.SetHiddenUnitsTab, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.UITable_11 = uitable(app.SetHiddenUnitsTab);
            app.UITable_11.ColumnName = {''; ''; ''; ''};
            app.UITable_11.RowName = {};
            app.SelectMotherWaveletDropDownLabel_3 = uilabel(app.SetHiddenUnitsTab);
            app.SelectMotherWaveletDropDownLabel_3.Text = 'Select Weight';
            app.SelectMotherWaveletDropDown_3 = uidropdown(app.SetHiddenUnitsTab);
            app.SelectMotherWaveletDropDown_3.Items = {'W', 'WW', 'a', 'b'};
            app.SelectMotherWaveletDropDown_3.Value = 'W';
            app.ViewButton_2 = uibutton(app.SetHiddenUnitsTab, 'push');
            app.ViewButton_2.ButtonPushedFcn = createCallbackFcn(app, @ViewButton_2Pushed, true);
            app.ViewButton_2.Text = 'View';
            app.NextButton_6 = uibutton(app.SetHiddenUnitsTab, 'push');
            app.NextButton_6.ButtonPushedFcn = createCallbackFcn(app, @NextButton_6Pushed, true);
            app.NextButton_6.Text = 'Next>>';
            app.RunModelTab_2 = uitab(app.TabGroup);
            app.RunModelTab_2.Title = 'Run Model';
            app.NumberofEpochsIterationsEditField_2Label = uilabel(app.RunModelTab_2);
            app.NumberofEpochsIterationsEditField_2Label.Text = 'Number of Epochs/ Iterations';
            app.NumberofEpochsIterationsEditField_2 = uieditfield(app.RunModelTab_2, 'numeric');
            app.NumberofEpochsIterationsEditField_2.Value = 1000;
            app.MaximumErrorValueEditField_2Label = uilabel(app.RunModelTab_2);
            app.MaximumErrorValueEditField_2Label.Text = 'Maximum Error Value';
            app.MaximumErrorValueEditField_2 = uieditfield(app.RunModelTab_2, 'numeric');
            app.MaximumErrorValueEditField_2.Value = 0.05;
            app.LearningRateEditField_2Label = uilabel(app.RunModelTab_2);
            app.LearningRateEditField_2Label.Text = 'Learning Rate';
            app.LearningRateEditField_2 = uieditfield(app.RunModelTab_2, 'numeric');
            app.LearningRateEditField_2.Value = 0.1;
            app.RunModelButton_2 = uibutton(app.RunModelTab_2, 'push');
            app.RunModelButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunModelButton_2Pushed, true);
            app.RunModelButton_2.Text = 'Run Model';
            app.MSEEditFieldLabel = uilabel(app.RunModelTab_2);
            app.MSEEditFieldLabel.Text = 'MSE';
            app.MSEEditField = uieditfield(app.RunModelTab_2, 'numeric');
            app.TrainingIndicatorsLabel_2 = uilabel(app.RunModelTab_2);
            app.TrainingIndicatorsLabel_2.Text = 'Training Indicators';
            app.CorrelationCoefficientEditField_3Label = uilabel(app.RunModelTab_2);
            app.CorrelationCoefficientEditField_3Label.Text = 'Correlation Coefficient';
            app.CorrelationCoefficientEditField_3 = uieditfield(app.RunModelTab_2, 'numeric');
            app.TestingIndicatorsLabel = uilabel(app.RunModelTab_2);
            app.TestingIndicatorsLabel.Text = 'Testing Indicators';
            app.MSEEditField_2Label = uilabel(app.RunModelTab_2);
            app.MSEEditField_2Label.Text = 'MSE';
            app.MSEEditField_2 = uieditfield(app.RunModelTab_2, 'numeric');
            app.CorrelationCoefficientEditField_4Label = uilabel(app.RunModelTab_2);
            app.CorrelationCoefficientEditField_4Label.Text = 'Correlation Coefficient';
            app.CorrelationCoefficientEditField_4 = uieditfield(app.RunModelTab_2, 'numeric');
            app.UIAxes_2 = uiaxes(app.RunModelTab_2);
            title(app.UIAxes_2, 'Training Data')
            xlabel(app.UIAxes_2, 'Simulated Values')
            ylabel(app.UIAxes_2, 'Observed Values')
            app.UIAxes_3 = uiaxes(app.RunModelTab_2);
            title(app.UIAxes_3, 'Testing Data')
            xlabel(app.UIAxes_3, 'Simulated Values')
            ylabel(app.UIAxes_3, 'Obseved Values')
            app.LearningRateEditField_2Label_2 = uilabel(app.RunModelTab_2);
            app.LearningRateEditField_2Label_2.Text = 'Momentum Rate';
            app.momentumrateEditField = uieditfield(app.RunModelTab_2, 'numeric');
            app.momentumrateEditField.Value = 0.1;
            app.NextButton_3 = uibutton(app.RunModelTab_2, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next>>';
            app.PBiasEditFieldLabel = uilabel(app.RunModelTab_2);
            app.PBiasEditFieldLabel.Text = 'P-Bias';
            app.PBiasEditField = uieditfield(app.RunModelTab_2, 'numeric');
            app.PBiasEditField_2Label = uilabel(app.RunModelTab_2);
            app.PBiasEditField_2Label.Text = 'P-Bias';
            app.PBiasEditField_2 = uieditfield(app.RunModelTab_2, 'numeric');
            app.Label_2 = uilabel(app.RunModelTab_2);
            app.Label_2.Text = '[0-1]';
            app.Label_3 = uilabel(app.RunModelTab_2);
            app.Label_3.Text = '[0-1]';
            app.RUNMODELLabel = uilabel(app.RunModelTab_2);
            app.RUNMODELLabel.Text = 'RUN MODEL';
            app.Image_10 = uiimage(app.RunModelTab_2);
            app.Image_10.ImageSource = 'bits logo.png';
            app.DownloadFinalWeightsButton = uibutton(app.RunModelTab_2, 'push');
            app.DownloadFinalWeightsButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadFinalWeightsButtonPushed, true);
            app.DownloadFinalWeightsButton.Text = 'Download Final Weights';
            app.FutureDataTab = uitab(app.TabGroup);
            app.FutureDataTab.Title = 'Future Data';
            app.UploadFutureDataButton = uibutton(app.FutureDataTab, 'push');
            app.UploadFutureDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFutureDataButtonPushed, true);
            app.UploadFutureDataButton.Text = 'Upload Future Data';
            app.UIAxes4 = uiaxes(app.FutureDataTab);
            title(app.UIAxes4, 'Future Data')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.DownloadPredictedValuesButton = uibutton(app.FutureDataTab, 'push');
            app.DownloadPredictedValuesButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadPredictedValuesButtonPushed, true);
            app.DownloadPredictedValuesButton.Text = 'Download Predicted Values';
            app.NumberofRecordsEditField_2Label = uilabel(app.FutureDataTab);
            app.NumberofRecordsEditField_2Label.Text = 'Number of Records';
            app.NumberofRecordsEditField_2 = uieditfield(app.FutureDataTab, 'numeric');
            app.SelectFileTypeDropDown_3Label = uilabel(app.FutureDataTab);
            app.SelectFileTypeDropDown_3Label.Text = 'Select File Type';
            app.SelectFileTypeDropDown_3 = uidropdown(app.FutureDataTab);
            app.SelectFileTypeDropDown_3.Items = {'Excel', 'CSV'};
            app.SelectFileTypeDropDown_3.Value = 'Excel';
            app.UITable_9 = uitable(app.FutureDataTab);
            app.UITable_9.ColumnName = {''};
            app.UITable_9.RowName = {};
            app.FUTUREDATALabel = uilabel(app.FutureDataTab);
            app.FUTUREDATALabel.Text = 'FUTURE DATA';
            app.Image_12 = uiimage(app.FutureDataTab);
            app.Image_12.ImageSource = 'bits logo.png';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.InputDataTab.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end